classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label_3    matlab.ui.control.Label
        Label_2    matlab.ui.control.Label
        getAnswer  matlab.ui.control.Button
        upImage    matlab.ui.control.Button
        Label      matlab.ui.control.Label
        answer     matlab.ui.control.Image
        InitImage  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitImage.ImageSource = 'init.png';
            app.answer.ImageSource = 'init.png';
        end

        % Button pushed function: upImage
        function upImageButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.*';'*.bmp';'*.jpg';'*.tif';'*.jpg'},'选择图像');
            image=[pathname,filename];
            if isequal(filename,0)||isequal(pathname,0)
            errordlg('您还没有选取图片','温馨提示');
            return;
            else
            app.InitImage.ImageSource =image;
            end
        end

        % Button pushed function: getAnswer
        function getAnswerButtonPushed(app, event)
            app.answer.ImageSource ='Loading.png';
            % addpath('src');
            parser = Parser();
            img = imread(app.InitImage.ImageSource);
            [~, im1, im2] = parser.parse(img);
            app.answer.ImageSource = im1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitImage = uiimage(app.UIFigure);
            app.answer = uiimage(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数独识别与求解';
            app.upImage = uibutton(app.UIFigure, 'push');
            app.upImage.ButtonPushedFcn = createCallbackFcn(app, @upImageButtonPushed, true);
            app.upImage.Text = '上传图片';
            app.getAnswer = uibutton(app.UIFigure, 'push');
            app.getAnswer.ButtonPushedFcn = createCallbackFcn(app, @getAnswerButtonPushed, true);
            app.getAnswer.Text = '求解';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '原始图片';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '结果展示';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end